classdef LabA1App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlayROSbagButton                matlab.ui.control.Button
        VolumeTextArea                  matlab.ui.control.TextArea
        VolumeTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        CalculateRobotsWorkspaceButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        BuildWallButton                 matlab.ui.control.Button
        NOTETextArea                    matlab.ui.control.TextArea
        PlaceBricksButton               matlab.ui.control.Button
        BrickPileYSlider                matlab.ui.control.Slider
        BrickPileYSliderLabel           matlab.ui.control.Label
        BrickPileXSlider                matlab.ui.control.Slider
        BrickPileXSliderLabel           matlab.ui.control.Label
        CreatetheWorkspaceButton        matlab.ui.control.Button
        PlaceRobotButton                matlab.ui.control.Button
        RobotBaseYglobalSlider          matlab.ui.control.Slider
        RobotBaseYglobalSliderLabel     matlab.ui.control.Label
        RobotBaseXglobalSlider          matlab.ui.control.Slider
        RobotBaseXglobalSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        % A 'Lab1Assignment' object
        quangWorkspace 

        % Handle and name of the created figure
        fig_h
        figName = 'IR_A1_Workspace';

        % Robot Position
        robotX = 0;
        robotY = 0;
        
        % Brick pile Position with respect to the robot
        brickX = 0.28;
        brickY = -0.25;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatetheWorkspaceButton
        function CreatetheWorkspaceButtonPushed(app, event)
            if isempty(app.fig_h) || isempty(findobj('type','figure','name',app.figName))
                app.quangWorkspace = Lab1Assignment; % create the workspace
                app.fig_h = get(groot,'CurrentFigure'); % retrieve the handle of the current figure
                app.StatusTextArea.Value = 'No wall'; % text inform that no wall was built
            end
        end

        % Value changed function: RobotBaseXglobalSlider
        function RobotBaseXglobalSliderValueChanged(app, event)
            app.robotX = app.RobotBaseXglobalSlider.Value;
        end

        % Value changed function: RobotBaseYglobalSlider
        function RobotBaseYglobalSliderValueChanged(app, event)
            app.robotY = app.RobotBaseYglobalSlider.Value;
        end

        % Button pushed function: PlaceRobotButton
        function PlaceRobotButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                app.quangWorkspace.PlaceRobot(transl(app.robotX, app.robotY, 0));
            end
        end

        % Value changed function: BrickPileXSlider
        function BrickPileXSliderValueChanged(app, event)
            app.brickX = app.BrickPileXSlider.Value;
        end

        % Value changed function: BrickPileYSlider
        function BrickPileYSliderValueChanged(app, event)
            app.brickY = app.BrickPileYSlider.Value;        
        end

        % Button pushed function: PlaceBricksButton
        function PlaceBricksButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                app.quangWorkspace.PlaceBricks(app.brickX,app.brickY);
                app.StatusTextArea.Value = 'No wall';
            end
        end

        % Callback function: BuildWallButton, StatusTextArea
        function BuildWallButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                if isempty(app.quangWorkspace.robot) || isempty(app.quangWorkspace.brickPile)
                    app.StatusTextArea.Value = 'No robot or brick pile !';
                else 
                    app.StatusTextArea.Value = 'Building wall...';
                    app.quangWorkspace.BuildWall()
                    app.StatusTextArea.Value = 'Wall built successful!';
                end
            else 
                app.StatusTextArea.Value = 'Not enough value!';
            end
        end

        % Callback function: CalculateRobotsWorkspaceButton, VolumeTextArea
        function CalculateRobotsWorkspaceButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                [taskVolume_1,taskVolume_2] = app.quangWorkspace.CalculateWorkspace();
                
                app.VolumeTextArea.Value = ['The workspace volume estimated using half-sphere and half-cylinder is ',num2str(taskVolume_1),' cubic-meters. ' ...
                'The workspace volume estimated using convex hull is ',num2str(taskVolume_2),' cubic-meters'];
            end 
        end

        % Button pushed function: PlayROSbagButton
        function PlayROSbagButtonPushed(app, event)
            if  ~isempty(findobj('type','figure','name',app.figName))
                app.quangWorkspace.PlayROSbag();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotBaseXglobalSliderLabel = uilabel(app.UIFigure);
            app.RobotBaseXglobalSliderLabel.Text = 'Robot Base- X global';
            app.RobotBaseXglobalSlider = uislider(app.UIFigure);
            app.RobotBaseXglobalSlider.Limits = [-0.2 0.8];
            app.RobotBaseXglobalSlider.ValueChangedFcn = createCallbackFcn(app, @RobotBaseXglobalSliderValueChanged, true);
            app.RobotBaseYglobalSliderLabel = uilabel(app.UIFigure);
            app.RobotBaseYglobalSliderLabel.Text = 'Robot Base- Y global';
            app.RobotBaseYglobalSlider = uislider(app.UIFigure);
            app.RobotBaseYglobalSlider.Limits = [-0.6 0.6];
            app.RobotBaseYglobalSlider.ValueChangedFcn = createCallbackFcn(app, @RobotBaseYglobalSliderValueChanged, true);
            app.PlaceRobotButton = uibutton(app.UIFigure, 'push');
            app.PlaceRobotButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceRobotButtonPushed, true);
            app.PlaceRobotButton.Text = 'Place Robot';
            app.CreatetheWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.CreatetheWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CreatetheWorkspaceButtonPushed, true);
            app.CreatetheWorkspaceButton.Text = 'Create the Workspace';
            app.BrickPileXSliderLabel = uilabel(app.UIFigure);
            app.BrickPileXSliderLabel.Text = 'Brick Pile - X';
            app.BrickPileXSlider = uislider(app.UIFigure);
            app.BrickPileXSlider.Limits = [0.25 0.3];
            app.BrickPileXSlider.ValueChangedFcn = createCallbackFcn(app, @BrickPileXSliderValueChanged, true);
            app.BrickPileXSlider.Value = 0.25;
            app.BrickPileYSliderLabel = uilabel(app.UIFigure);
            app.BrickPileYSliderLabel.Text = 'Brick Pile - Y';
            app.BrickPileYSlider = uislider(app.UIFigure);
            app.BrickPileYSlider.Limits = [-0.25 -0.2];
            app.BrickPileYSlider.MajorTicks = [-0.3 -0.28 -0.26 -0.24 -0.22 -0.2];
            app.BrickPileYSlider.ValueChangedFcn = createCallbackFcn(app, @BrickPileYSliderValueChanged, true);
            app.BrickPileYSlider.Value = -0.25;
            app.PlaceBricksButton = uibutton(app.UIFigure, 'push');
            app.PlaceBricksButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceBricksButtonPushed, true);
            app.PlaceBricksButton.Text = 'Place Bricks';
            app.NOTETextArea = uitextarea(app.UIFigure);
            app.NOTETextArea.Value = {'Brick Pile''s position with respect to the Robot'};
            app.BuildWallButton = uibutton(app.UIFigure, 'push');
            app.BuildWallButton.ButtonPushedFcn = createCallbackFcn(app, @BuildWallButtonPushed, true);
            app.BuildWallButton.Text = 'Build Wall';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'WholeImage.png');
            app.CalculateRobotsWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.CalculateRobotsWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRobotsWorkspaceButtonPushed, true);
            app.CalculateRobotsWorkspaceButton.Text = 'Calculate Robot''s Workspace';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'LinearUR3.png');
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.ValueChangedFcn = createCallbackFcn(app, @BuildWallButtonPushed, true);
            app.VolumeTextAreaLabel = uilabel(app.UIFigure);
            app.VolumeTextAreaLabel.Text = 'Volume:';
            app.VolumeTextArea = uitextarea(app.UIFigure);
            app.VolumeTextArea.ValueChangedFcn = createCallbackFcn(app, @CalculateRobotsWorkspaceButtonPushed, true);
            app.PlayROSbagButton = uibutton(app.UIFigure, 'push');
            app.PlayROSbagButton.ButtonPushedFcn = createCallbackFcn(app, @PlayROSbagButtonPushed, true);
            app.PlayROSbagButton.Text = 'Play ROS bag';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabA1App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end